classdef fork_feed < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        TabGroup               matlab.ui.container.TabGroup       % Tab 1, ...
        Tab                    matlab.ui.container.Tab            % Tab 1
        UIAxes1                matlab.ui.control.UIAxes           % X-channel
        UIAxes2                matlab.ui.control.UIAxes           % Y-Channel
        Panel                  matlab.ui.container.Panel          % Panel
        Label                  matlab.ui.control.Label            % End
        EndSl                  matlab.ui.control.Slider           % [1 100]
        Label2                 matlab.ui.control.Label            % Begin
        Begsl                  matlab.ui.control.Slider           % [1 100]
        App_cut                matlab.ui.control.Button           % Apply
        ap_fit                 matlab.ui.control.Button           % Fit
        LabelNumericEditField  matlab.ui.control.Label            % Drive V
        DV                     matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelTextArea          matlab.ui.control.Label            % Fit par...
        textfit                matlab.ui.control.TextArea         % x0
        Tab2                   matlab.ui.container.Tab            % Tab 2
        Panel2                 matlab.ui.container.Panel          % Panel
        openrs                 matlab.ui.control.Button           % open re...
        recal                  matlab.ui.control.Button           % recall
        filt                   matlab.ui.control.Button           % Filter
        LabelNumericEditField2 matlab.ui.control.Label            % Filter num
        filter_num             matlab.ui.control.NumericEditField % [1 Inf]
        LabelSlider            matlab.ui.control.Label            % X0
        X_0                    matlab.ui.control.Slider           % [-1 1]
        Label3                 matlab.ui.control.Label            % X1
        X_1                    matlab.ui.control.Slider           % [-1 1]
        Label4                 matlab.ui.control.Label            % Y0
        Y_0                    matlab.ui.control.Slider           % [-1 1]
        Label5                 matlab.ui.control.Label            % Y1
        Y_1                    matlab.ui.control.Slider           % [-1 1]
        UIAxes3                matlab.ui.control.UIAxes           % X-Chann...
        UIAxes4                matlab.ui.control.UIAxes           % Y-Chann...
        Closeapp               matlab.ui.control.Button           % Close app
        Tab3                   matlab.ui.container.Tab            % Tab 3
        UIAxes5                matlab.ui.control.UIAxes           % Ycorr v...
        UIAxes6                matlab.ui.control.UIAxes           % Rcorr
        LabelTextArea2         matlab.ui.control.Label            % Phase s...
        phsh                   matlab.ui.control.TextArea         % phi
        save_but               matlab.ui.control.Button           % printAll
        Tab4                   matlab.ui.container.Tab            % Tab 4
        UIAxes7                matlab.ui.control.UIAxes           % Phase
    end


    properties (Access = public)
        ff; % after import
        begl; % for cut
        endl; % Descriptionl
        ff1; % after cut
        fparx; % fit parameters X
        fpary; % fit parameters Y
        xf; % x fit
        yf; % y fit
        ffs; % regular sweer
        xcorr; % Description
        ycorr; % Description
        rcorr; % Description
        xcorr_old; % Description
        ycorr_old; % Description
        sw_1; % Description
        sw_2; % Description
        freq_old; % Description
        freq_new; % Description
        for_circl; % Description
        XY; % Description
        X_max; % Description
       
        
    end

    methods (Access = public)
        
        function Par = CircleFitByPratt(app)
            %    cirlce fit
            n = size(app.XY,1);      % number of data points
            centroid = mean(app.XY);   % the centroid of the data setM
            Mxx=0; Myy=0; Mxy=0; Mxz=0; Myz=0; Mzz=0;
            for i=1:n
                Xi = app.XY(i,1) - centroid(1);  %  centering data
                Yi = app.XY(i,2) - centroid(2);  %  centering data
                Zi = Xi*Xi + Yi*Yi;
                Mxy = Mxy + Xi*Yi;
                Mxx = Mxx + Xi*Xi;
                Myy = Myy + Yi*Yi;
                Mxz = Mxz + Xi*Zi;
                Myz = Myz + Yi*Zi;
                Mzz = Mzz + Zi*Zi;
            end
            Mxx = Mxx/n;
            Myy = Myy/n;
            Mxy = Mxy/n;
            Mxz = Mxz/n;
            Myz = Myz/n;
            Mzz = Mzz/n;
            %    computing the coefficients of the characteristic polynomial
            Mz = Mxx + Myy;
            Cov_xy = Mxx*Myy - Mxy*Mxy;
            Mxz2 = Mxz*Mxz;
            Myz2 = Myz*Myz;
            A2 = 4*Cov_xy - 3*Mz*Mz - Mzz;
            A1 = Mzz*Mz + 4*Cov_xy*Mz - Mxz2 - Myz2 - Mz*Mz*Mz;
            A0 = Mxz2*Myy + Myz2*Mxx - Mzz*Cov_xy - 2*Mxz*Myz*Mxy + Mz*Mz*Cov_xy;
            A22 = A2 + A2;
            epsilon=1e-12; 
            ynew=1e+20;
            IterMax=20;
            xnew = 0;
            %    Newton's method starting at x=0
            for iter=1:IterMax
                yold = ynew;
                ynew = A0 + xnew*(A1 + xnew*(A2 + 4.*xnew*xnew));
                if (abs(ynew)>abs(yold))
                    disp('Newton-Pratt goes wrong direction: |ynew| > |yold|');
                    xnew = 0;
                    break;
                end
                Dy = A1 + xnew*(A22 + 16*xnew*xnew);
                xold = xnew;
                xnew = xold - ynew/Dy;
                if (abs((xnew-xold)/xnew) < epsilon), break, end
                if (iter >= IterMax)
                    disp('Newton-Pratt will not converge');
                    xnew = 0;
                end
                if (xnew<0.)
                    fprintf(1,'Newton-Pratt negative root:  x=%f\n',xnew);
                    xnew = 0;
                end
            end              
            %    computing the circle parameters
            DET = xnew*xnew - xnew*Mz + Cov_xy;
            Center = [Mxz*(Myy-xnew)-Myz*Mxy , Myz*(Mxx-xnew)-Mxz*Mxy]/DET/2;          
            Par = [Center+centroid , sqrt(Center*Center'+Mz+2*xnew)];
        end    %    CircleFitByPratt

        function cv = xchanfit(app,parameter,w)
            %    fit of X channel
            Q=real(parameter(1));
            A=real(parameter(2));
            w0=real(parameter(3));
            app.X_max=w0;
            cv=zeros(length(w),1);
            for ii=1:length(w)
                cv(ii,1)=A*(w0*w(ii,1)/Q)/((w(ii,1)^2-w0^2)^2+w(ii,1)^2*w0^2/Q^2);
            end
        end

        function cv = ychanfit(app,parameter,w)
            %    fit of Y channel
            w0=app.X_max;
%             idx=knnsearch(w1,w0);
%             num=1250;
%             w(:)=w1(idx-num:idx+num,1);
            Q=real(parameter(1));
            A=real(parameter(2));                       
            cv=zeros(length(w),1);
            for ii=1:length(w)
                cv(ii,1)=-A*(w(ii,1)^2-w0^2)/((w(ii,1)^2-w0^2)^2+w(ii,1)^2*w0^2/Q^2);
            end
        end

        function beta = X_wfit(app,x,y)
            % @(b,x)(b(1)+b(2)*exp(-b(3)*x));
            [~,idm]=max(y(:));
            num=2500; % range in Hz
            k1=knnsearch(x,x(idm)-num);
            k2=knnsearch(x,x(idm)+num);
            
            W=ones(length(x),1);
            W(k1:k2,1)=10;
            modelfun = @(b,w)((b(2).*b(3).*w./b(1))./(((w.^2-b(3).^2).^2)+w.^2.*b(3).^2./b(1).^2));
            x0=[20;1;x(idm)];
            [beta,R,J,CovB] = nlinfit(x,y,modelfun,x0,'Weights',W);
            app.X_max=beta(3);
                     
        end

        function beta = Y_wfit(app,x,y)
            % @(b,x)(b(1)+b(2)*exp(-b(3)*x));
            idm=knnsearch(x,app.X_max);
            num=2500; % range in Hz
            k1=knnsearch(x,x(idm)-num);
            k2=knnsearch(x,x(idm)+num);
            app.X_max=x(idm);
            W=ones(length(x),1);
            W(k1:k2,1)=10;
            modelfun = @(b,w)(-b(2).*(w.^2-app.X_max.^2)./(((w.^2-app.X_max.^2).^2)+w.^2.*app.X_max.^2./b(1).^2));
            x0=[20;1];
            [beta,R,J,CovB] = nlinfit(x,y,modelfun,x0,'Weights',W);
            
                
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning('off','all');
            [file,ruta]=uigetfile({'*.dat'},'Select a long sweep');
            cd(ruta);
            ff2=importdata(file);
            app.ff=ff2.data;
            app.ff(end,:)=[];
            app.ff(1,:)=[];
            app.begl=1;
            app.endl=length(app.ff);
            
            app.EndSl.Limits = [app.begl app.endl];
            app.EndSl.Value = app.endl;
            app.Begsl.Limits = [app.begl app.endl];
            app.Begsl.Value = app.begl;
            %app.ff(:,1)=app.ff(:,1)-app.ff(1,1);
            %app.ff(:,end)=[];
            app.ff1=app.ff;
           
            app.UIAxes1.cla;
            plot(app.UIAxes1,app.ff(:,2),app.ff(:,3));
            app.UIAxes2.cla;
            plot(app.UIAxes2,app.ff(:,2),app.ff(:,4));
             warning('on','all');
        end

        % Begsl value changed function
        function BegslValueChanged(app)
            app.begl = round(app.Begsl.Value);
            
            app.UIAxes1.cla;
            hold(app.UIAxes1,'on');            
            plot(app.UIAxes1,app.ff(1:app.begl,2),app.ff(1:app.begl,3),'-b');
            plot(app.UIAxes1,app.ff(app.endl:end,2),app.ff(app.endl:end,3),'-b');
            
            app.UIAxes2.cla;
            hold(app.UIAxes2,'on');
            plot(app.UIAxes2,app.ff(1:app.begl,2),app.ff(1:app.begl,4),'-b');
            plot(app.UIAxes2,app.ff(app.endl:end,2),app.ff(app.endl:end,4),'-b');
            
        end

        % EndSl value changed function
        function EndSlValueChanged(app)
            app.endl = round(app.EndSl.Value);
            app.UIAxes1.cla;
            hold(app.UIAxes1,'on');            
            plot(app.UIAxes1,app.ff(1:app.begl,2),app.ff(1:app.begl,3),'-b');
            plot(app.UIAxes1,app.ff(app.endl:end,2),app.ff(app.endl:end,3),'-b');
            
            app.UIAxes2.cla;
            hold(app.UIAxes2,'on');
            plot(app.UIAxes2,app.ff(1:app.begl,2),app.ff(1:app.begl,4),'-b');
            plot(app.UIAxes2,app.ff(app.endl:end,2),app.ff(app.endl:end,4),'-b');
            
        end

        % ap_fit button pushed function
        function ap_fitButtonPushed(app)
            app.fparx = polyfit(app.ff1(:,2),app.ff1(:,3),4);
            app.fpary = polyfit(app.ff1(:,2),app.ff1(:,4),4);
            app.xf = polyval(app.fparx,app.ff1(:,2));
            app.yf = polyval(app.fpary,app.ff1(:,2));
            
            
            app.X_0.Limits = [-0.002*abs(app.fparx(4)),0.002*abs(app.fparx(5))];
            app.X_1.Limits = [-0.002*abs(app.fparx(3)),0.002*abs(app.fparx(4))];
            app.Y_0.Limits = [-0.02*abs(app.fpary(5)),0.02*abs(app.fpary(5))];
            app.Y_1.Limits = [-0.002*abs(app.fpary(4)),0.002*abs(app.fpary(4))];
            
            app.UIAxes1.cla;
            hold(app.UIAxes1,'on');
            plot(app.UIAxes1,app.ff1(:,2),app.ff1(:,3),'sb');
            plot(app.UIAxes1,app.ff1(:,2),app.xf,'-r');
            
            app.UIAxes2.cla;
            hold(app.UIAxes2,'on');
            plot(app.UIAxes2,app.ff1(:,2),app.ff1(:,4),'sb');
            plot(app.UIAxes2,app.ff1(:,2),app.yf,'-r');
            
            sx4=num2str(app.fparx(1),12);
            sx3=num2str(app.fparx(2),12);
            sx2=num2str(app.fparx(3),12);
            sx1=num2str(app.fparx(4),12);
            sx0=num2str(app.fparx(5),12);
            
            sy4=num2str(app.fpary(1),12);
            sy3=num2str(app.fpary(2),12);
            sy2=num2str(app.fpary(3),12);
            sy1=num2str(app.fpary(4),12);
            sy0=num2str(app.fpary(5),12);
            ssx4=['x4 = ',sx4 ];
            ssx3=['x3 = ',sx3];
            ssx2=['x2 = ',sx2];
            ssx1=['x1 = ',sx1];
            ssx0=['x0 = ',sx0];
            ssy4=['y4 = ',sy4];
            ssy3=['y3 = ',sy3];
            ssy2=['y2 = ',sy2];
            ssy1=['y1 = ',sy1];
            ssy0=['y0 = ',sy0];
            app.textfit.Value = {ssx0; ssx1; ssx2; ssx3; ssx4; ''; ssy0; ssy1; ssy2; ssy3; ssy4};

        end

        % App_cut button pushed function
        function App_cutButtonPushed(app)
            app.ff1=[app.ff(1:app.begl,:);app.ff(app.endl:end,:)];
            app.UIAxes1.cla;
            hold(app.UIAxes1,'on');
            plot(app.UIAxes1,app.ff1(:,2),app.ff1(:,3),'-b');
            
            app.UIAxes2.cla;
            hold(app.UIAxes2,'on');
            plot(app.UIAxes2,app.ff1(:,2),app.ff1(:,4),'-b');
        end

        % openrs button pushed function
        function openrsButtonPushed(app)
           
            [file,ruta]=uigetfile({'*.dat'},'Select a regular sweep');
            cd(ruta);
            ff2=importdata(file);
            app.ffs=ff2.data;
            app.ffs(end,:)=[];
            app.ffs(1,:)=[];
            x1=polyval(app.fparx,app.ffs(:,2));
            y1=polyval(app.fpary,app.ffs(:,2));
            app.xcorr(:,1)=app.ffs(:,3)-x1(:);
            app.ycorr(:,1)=app.ffs(:,4)-y1(:);
            m1=min(app.xcorr(:,1));
            m2=max(app.xcorr(:,1));
            m3=min(app.ycorr(:,1));
            m4=max(app.ycorr(:,1));
            x0=(m2-m1)/2;
            y0=(m4-m3)/2;
            c1(:,1)=abs(sqrt(x0^2-(app.xcorr(:,1)-x0).^2));
            c2(:,1)=-abs(sqrt(x0^2-(app.xcorr(:,1)-x0).^2));
            app.rcorr(:,1)=sqrt(app.xcorr(:,1).^2+app.ycorr(:,1).^2);


            app.UIAxes3.cla;
            plot(app.UIAxes3,app.ffs(:,2),app.xcorr(:,1),'-b');
            
            app.UIAxes4.cla;
            plot(app.UIAxes4,app.ffs(:,2),app.ycorr(:,1),'-b');
            
            app.UIAxes5.cla;
            hold(app.UIAxes5,'on');
            plot(app.UIAxes5,app.xcorr(:,1),app.ycorr(:,1),'-b');
            plot(app.UIAxes5,app.xcorr(:,1),c1(:,1),'-r');
            plot(app.UIAxes5,app.xcorr(:,1),c2(:,1),'-r');
             
            app.UIAxes6.cla;
            plot(app.UIAxes6,app.ffs(:,2),app.rcorr(:,1),'-b');
            
            sx4=num2str(app.fparx(1),12);
            sx3=num2str(app.fparx(2),12);
            sx2=num2str(app.fparx(3),12);
            sx1=num2str(app.fparx(4),12);
            sx0=num2str(app.fparx(5),12);
            
            sy4=num2str(app.fpary(1),12);
            sy3=num2str(app.fpary(2),12);
            sy2=num2str(app.fpary(3),12);
            sy1=num2str(app.fpary(4),12);
            sy0=num2str(app.fpary(5),12);
            ssx4=['x4 = ',sx4];
            ssx3=['x3 = ',sx3];
            ssx2=['x2 = ',sx2];
            ssx1=['x1 = ',sx1];
            ssx0=['x0 = ',sx0];
            ssy4=['y4 = ',sy4];
            ssy3=['y3 = ',sy3];
            ssy2=['y2 = ',sy2];
            ssy1=['y1 = ',sy1];
            ssy0=['y0 = ',sy0];
            app.textfit.Value = {ssx0; ssx1; ssx2; ssx3; ssx4; ''; ssy0; ssy1; ssy2; ssy3; ssy4};
%             app.xorr_old(:,1)=app.xcorr(:,1);
%             app.ycorr_old(:,1)=app.ycorr(:,1);
%             app.freq_old(:,1)=app.ffs(:,2);
        end

        % Closeapp button pushed function
        function CloseappButtonPushed(app)
            delete(app);
        end

        % recal button pushed function
        function recalButtonPushed(app)
            %app.xcorr(:,1)=app.xcorr_old(f1:f2)
            m3=min(app.ycorr(:,1));
            m4=max(app.ycorr(:,1));
            y0=(m4+m3)/2; % Y chan shift
            app.fpary(5)=app.fpary(5)+y0;
            a1=mean(app.ffs(1:50,2));
            a2=mean(app.ffs(end-50:end,2));
            b1=mean(app.xcorr(1:50,1));
            b2=mean(app.xcorr(end-50:end,1));
            
            k=(b2-b1)/(a2-a1);
            
            app.fparx(4)=app.fparx(4)+k;
            
            
            x1=polyval(app.fparx,app.ffs(:,2));
            y1=polyval(app.fpary,app.ffs(:,2));
            app.xcorr(:,1)=app.ffs(:,3)-x1(:);
            app.ycorr(:,1)=app.ffs(:,4)-y1(:);
%             b3=mean(app.ycorr(1:50,1));
%             b4=mean(app.ycorr(end-50:end,1));
%             ky=(b4+b3)/(a2+a1);
%             
%             if (b3-ky*a1<0)
%                 yt1=2*b3-ky*a1;
%                 yt2=2*b4-ky*a2;
%             else
%                 yt1=2*ky*a1-b3;
%                 yt2=2*ky*a2-b4;
%             end
%             
%             ky1=(yt2-yt1)/(a2-a1);
%             app.fpary(4)=app.fpary(4)+ky1;
%             y1=polyval(app.fpary,app.ffs(:,2));
%             app.ycorr(:,1)=app.ffs(:,4)-y1(:);
%             m3=min(app.ycorr(:,1));
%             m4=max(app.ycorr(:,1));
%             y0=(m4+m3)/2;
%             app.fpary(5)=app.fpary(5)+y0;
%             y1=polyval(app.fpary,app.ffs(:,2));
%             app.ycorr(:,1)=app.ffs(:,4)-y1(:);
            
            % fit by circle
            m1=(mean(app.xcorr(1:50,1))+mean(app.xcorr(end-50:end,1)))/2;
            app.fparx(5)=app.fparx(5)+m1;
            x1=polyval(app.fparx,app.ffs(:,2));
            app.xcorr(:,1)=app.ffs(:,3)-x1(:);
            app.XY=[app.xcorr,app.ycorr];
            papa=app.CircleFitByPratt(); % usage
            
            th = 0:pi/50:2*pi;
            xunit = papa(3) * cos(th) + papa(1);
            yunit = papa(3) * sin(th) + papa(2);
            % fit X channel
            [~,idm]=max(app.xcorr(:,1));
            app.X_max=app.ffs(idm,2);
            
            beta = app.X_wfit(app.ffs(:,2),app.xcorr(:,1));
            X_fitW=app.xchanfit(beta,app.ffs(:,2));
            
            betaY = app.Y_wfit(app.ffs(:,2),app.ycorr(:,1));
            Y_fitW=app.ychanfit(betaY,app.ffs(:,2));
            
            x_in=[1 20 32500];
            options = optimoptions(@fminunc,'MaxFunEvals',5000);
            lb = [0 1 15000];
            ub = [10000 40000 60000];
            [pfit1,resnorm,residuals] = lsqcurvefit(@app.xchanfit,x_in,app.ffs(:,2),app.xcorr(:,1),lb,ub,options);
            pfit1(1)
            pfit1(2)
            pfit1(3)
            %pfit1 = fitnlm(app.ffs(:,2),app.xcorr(:,1),@app.xchantfit,x_in);
            X_fit=app.xchanfit(pfit1,app.ffs(:,2));
            % fit Y channel
            y_in=[pfit1(1) pfit1(2)];
            options = optimoptions(@fminunc,'MaxFunEvals',5000);
            lb = [0.75*pfit1(1) 0];
            ub = [1.25*pfit1(1) 400000];
            [pfit2,resnorm,residuals] = lsqcurvefit(@app.ychanfit,y_in,app.ffs(:,2),app.ycorr(:,1),lb,ub,options);
            Y_fit=app.ychanfit(pfit2,app.ffs(:,2));
            pfit2(1)
            pfit2(2)
           
            R_fit(:,1)=sqrt(X_fitW(:,1).^2+Y_fitW(:,1).^2);
            R_fmax=max(R_fit(:,1));
            
            app.rcorr(:,1)=sqrt(app.xcorr(:,1).^2+app.ycorr(:,1).^2);
            %app.rcorr(:,1)=app.rcorr(:,1)-min(app.rcorr(:,1));
            [m5,if0]=max(app.rcorr(:,1));
            f0=app.ffs(if0,2);
            rk=m5/sqrt(2);
            fi1=knnsearch(app.rcorr(1:if0,1),rk);
            f1=app.ffs(fi1,2);
            fi22=knnsearch(app.rcorr(if0:end,1),rk);
            fi2=fi22+if0;
            f2=app.ffs(fi2,2);
            Q=f0/(f2-f1);
            keff=Q*app.DV.Value/m5;
            [yprime params2] = lorentzfit(app.ffs(:,2),app.rcorr(:,1));
            
            app.UIAxes3.cla;
            hold(app.UIAxes3,'on');
            plot(app.UIAxes3,app.ffs(:,2),app.xcorr(:,1),'-b');
           % plot(app.UIAxes3,app.ffs(:,2),X_fit,'-r');
            plot(app.UIAxes3,app.ffs(:,2),X_fitW,'-r');
            
            app.UIAxes4.cla;
            hold(app.UIAxes4,'on');
            plot(app.UIAxes4,app.ffs(:,2),app.ycorr(:,1),'-b');
            %plot(app.UIAxes4,app.ffs(:,2),Y_fit,'-r');
            plot(app.UIAxes4,app.ffs(:,2),Y_fitW,'-r');
%             plot(app.UIAxes4,a1,yt1,'xg')
%             plot(app.UIAxes4,a2,yt2,'xg')
%             plot(app.UIAxes4,a1,b3,'xr')
%             plot(app.UIAxes4,a2,b4,'xr')
%             
            app.UIAxes5.cla;
            hold(app.UIAxes5,'on');
            %app.UIaxes5.PlotBoxAspectRatio = [1,1];
            plot(app.UIAxes5,app.xcorr(:,1),app.ycorr(:,1),'-b');
            plot(app.UIAxes5,app.xcorr(1,1),app.ycorr(1,1),'sq');
            plot(app.UIAxes5,app.xcorr(end,1),app.ycorr(end,1),'x');
%             plot(app.UIAxes5,app.xcorr(:,1),c1(:,1),'-r');
%             plot(app.UIAxes5,app.xcorr(:,1),c2(:,1),'-r');
            %plot(app.UIAxes5,xunit,yunit,'-r');
             plot(app.UIAxes5,X_fitW,Y_fitW,'-r');
             %xlim(app.UIAxes5,[-0.2e-4 4.2e-4]);
             %ylim(app.UIAxes5,[-2.2e-4 2.2e-4])
             %app.UIAxes5.Position = [185 239 220 220];
            
            app.UIAxes6.cla;
            hold(app.UIAxes6,'on');
            plot(app.UIAxes6,app.ffs(:,2),app.rcorr(:,1),'ob');
            plot(app.UIAxes6,app.ffs(:,2),R_fit,'-r');
            plot(app.UIAxes6,app.ffs(:,2),yprime,'-g');
            
            sf0=num2str(f0,12);
            ssf0=['f0 = ',sf0];
            sq0=num2str(Q,12);
            ssq0=['Q = ',sq0];
            sk=num2str(keff,12);
            ssk=['Keff = ',sk];
            sx4=num2str(app.fparx(1),12);
            sx3=num2str(app.fparx(2),12);
            sx2=num2str(app.fparx(3),12);
            sx1=num2str(app.fparx(4),12);
            sx0=num2str(app.fparx(5),12);
            sy4=num2str(app.fpary(1),12);
            sy3=num2str(app.fpary(2),12);
            sy2=num2str(app.fpary(3),12);
            sy1=num2str(app.fpary(4),12);
            sy0=num2str(app.fpary(5),12);
            ssx4=['x4 = ',sx4];
            ssx3=['x3 = ',sx3];
            ssx2=['x2 = ',sx2];
            ssx1=['x1 = ',sx1];
            ssx0=['x0 = ',sx0];
            ssy4=['y4 = ',sy4];
            ssy3=['y3 = ',sy3];
            ssy2=['y2 = ',sy2];
            ssy1=['y1 = ',sy1];
            ssy0=['y0 = ',sy0];
            app.textfit.Value = {ssx0; ssx1; ssx2; ssx3; ssx4; ''; ssy0; ssy1; ssy2; ssy3; ssy4;ssf0;ssq0;ssk};
            
%             xph=(mean(app.xcorr(1:10,1))+mean(app.xcorr(end-10:end,1)))/2;
%             yph=(mean(app.ycorr(1:10,1))+mean(app.ycorr(end-10:end,1)))/2;
            xph=(X_fitW(1)+X_fitW(end))/2;
            yph=(Y_fitW(1)+Y_fitW(end))/2;

            kph=(yph)/(xph-papa(1));
            ph=atan(kph)*180/pi;
            
            Qx=beta(1);
            Qy=betaY(1);
            K_eff=Qx*app.DV.Value/R_fmax;
            sfm=num2str(app.X_max);
            ske=num2str(K_eff,5);
            sqx=num2str(Qx,5);
            sqy=num2str(Qy,5);
            sph=num2str(ph,5);
            sske=['Kef = ',ske];
            ssfm=['Fm = ',sfm];
            ssph=['ph = ',sph];
            ssqx=['Qx = ',sqx];
            ssqy=['Qy = ',sqy];
            app.phsh.Value = {ssph;ssqx;ssqy;ssfm;sske};
            phase(:)=180*atan(Y_fit(:)./X_fit(:))/pi;
            app.UIAxes7.cla;
            hold(app.UIAxes7,'on');
            plot(app.UIAxes7,app.ffs(:,2),phase,'-b');
        end

        % filt button pushed function
        function filtButtonPushed(app)
            medX = medfilt1(app.ffs(:,3),app.filter_num.Value);
            medY = medfilt1(app.ffs(:,4),app.filter_num.Value);
            app.ffs(:,3)=medX(:);
            app.ffs(:,4)=medY(:);
            app.ffs(1:app.filter_num.Value,:)=[];
            app.xcorr(1:app.filter_num.Value,:)=[];
            app.ycorr(1:app.filter_num.Value,:)=[];
            app.rcorr(1:app.filter_num.Value,:)=[];
            app.ffs(end-app.filter_num.Value:end,:)=[];
            app.xcorr(end-app.filter_num.Value:end,:)=[];
            app.ycorr(end-app.filter_num.Value:end,:)=[];
            app.rcorr(end-app.filter_num.Value:end,:)=[];
            
            x1=polyval(app.fparx,app.ffs(:,2));
            y1=polyval(app.fpary,app.ffs(:,2));
            app.xcorr(:,1)=app.ffs(:,3)-x1(:);
            app.ycorr(:,1)=app.ffs(:,4)-y1(:);
            
            m1=min(app.xcorr(:,1));
            m2=max(app.xcorr(:,1));
            m3=min(app.ycorr(:,1));
            m4=max(app.ycorr(:,1));
            x0=(m2+m1)/2;
            y0=(m4+m3)/2;
            R1=m2-x0;
            R2=m4-y0;
            R=(R1+R2)/2;
            c1=zeros(length(app.rcorr));
            c2=zeros(length(app.rcorr));
            c1(:,1)=y0+abs(sqrt(R2^2-(app.xcorr(:,1)-x0).^2));
            c2(:,1)=y0-abs(sqrt(R2^2-(app.xcorr(:,1)-x0).^2));
            
            app.rcorr(:,1)=sqrt(app.xcorr(:,1).^2+app.ycorr(:,1).^2);
                        
            app.UIAxes3.cla;
            plot(app.UIAxes3,app.ffs(:,2),app.xcorr(:,1),'-b');
            
            app.UIAxes4.cla;
            plot(app.UIAxes4,app.ffs(:,2),app.ycorr(:,1),'-b');
            
            app.UIAxes5.cla;
            hold(app.UIAxes5,'on');
            plot(app.UIAxes5,app.xcorr(:,1),app.ycorr(:,1),'-b');
            plot(app.UIAxes5,app.xcorr(:,1),c1(:,1),'-r');
            plot(app.UIAxes5,app.xcorr(:,1),c2(:,1),'-r');
             
            app.UIAxes6.cla;
            plot(app.UIAxes6,app.ffs(:,2),app.rcorr(:,1),'-b');
            
            sx4=num2str(app.fparx(1),12);
            sx3=num2str(app.fparx(2),12);
            sx2=num2str(app.fparx(3),12);
            sx1=num2str(app.fparx(4),12);
            sx0=num2str(app.fparx(5),12);
            sy4=num2str(app.fpary(1),12);
            sy3=num2str(app.fpary(2),12);
            sy2=num2str(app.fpary(3),12);
            sy1=num2str(app.fpary(4),12);
            sy0=num2str(app.fpary(5),12);
            ssx4=['x4 = ',sx4];
            ssx3=['x3 = ',sx3];
            ssx2=['x2 = ',sx2];
            ssx1=['x1 = ',sx1];
            ssx0=['x0 = ',sx0];
            ssy4=['y4 = ',sy4];
            ssy3=['y3 = ',sy3];
            ssy2=['y2 = ',sy2];
            ssy1=['y1 = ',sy1];
            ssy0=['y0 = ',sy0];
            app.textfit.Value = {ssx0; ssx1; ssx2; ssx3; ssx4; ''; ssy0; ssy1; ssy2; ssy3; ssy4};
        end

        % filter_num value changed function
        function filter_numValueChanged(app)
            value = app.filter_num.Value;
            
        end

        % save_but button pushed function
        function ButtonButtonPushed(app)
            
        end

        % save_but button pushed function
        function save_butButtonPushed(app)
            F1 = getframe(app.UIAxes1);
            imwrite(F1,'backgrX.png')
%             print(app.UIAxes1,'backgrX','-dpng');
%             print(app.UIAxes2,'backgrY','-dpng');
%             print(app.UIAxes3,'SweepX','-dpng');
%             print(app.UIAxes4,'SweepY','-dpng');
%             print(app.UIAxes5,'circle','-dpng');
%             print(app.UIAxes1,'rcorr','-dpng');
        end

        % f_sw1 value changed function
        function f_sw1ValueChanged(app)
            value = app.f_sw1.Value;
            
        end

        % f_sw2 value changed function
        function f_sw2ValueChanged(app)
            value = app.f_sw2.Value;
            
        end

        % sw_lims button pushed function
        function sw_limsButtonPushed(app)
            app.sw_1= knnsearch(app.freq_old,app.f_sw1.Value);
            app.sw_2= knnsearch(app.freq_old,app.f_sw2.Value);
            app.freq_new(:,1)=app.freq_old(app.sw_1:app.sw_2,1);
            app.xcorr(:,1)=app.xcorr_old(app.sw_1:app.sw_2,1);
            app.ycorr(:,1)=app.ycorr_old(app.sw_1:app.sw_2,1);
            app.for_circl=[app.xcorr,app.ycorr];
        end

        % X_0 value changed function
        function X_0ValueChanged(app)
            v = app.X_0.Value;
            app.fparx(5)=app.fparx(5)+v;
            x1=polyval(app.fparx,app.ffs(:,2));
            app.xcorr(:,1)=app.ffs(:,3)-x1(:);
            app.UIAxes3.cla;
            hold(app.UIAxes3,'on');
            plot(app.UIAxes3,app.ffs(:,2),app.xcorr(:,1),'-b');
        end

        % X_1 value changed function
        function X_1ValueChanged(app)
            v = app.X_1.Value;
            
            app.fparx(4)=app.fparx(4)+v;
            x1=polyval(app.fparx,app.ffs(:,2));
            app.xcorr(:,1)=app.ffs(:,3)-x1(:);
            app.UIAxes3.cla;
            hold(app.UIAxes3,'on');
            plot(app.UIAxes3,app.ffs(:,2),app.xcorr(:,1),'-b');
        end

        % Y_0 value changed function
        function Y_0ValueChanged(app)
            v = app.Y_0.Value;
            app.fpary(5)=app.fpary(5)+v;
            x1=polyval(app.fpary,app.ffs(:,2));
            app.ycorr(:,1)=app.ffs(:,4)-x1(:);
            app.UIAxes4.cla;
            hold(app.UIAxes4,'on');
            plot(app.UIAxes4,app.ffs(:,2),app.ycorr(:,1),'-b');
        end

        % Y_1 value changed function
        function Y_1ValueChanged(app)
            v = app.Y_1.Value;
            app.fpary(4)=app.fpary(4)+v;
            x1=polyval(app.fpary,app.ffs(:,2));
            app.ycorr(:,1)=app.ffs(:,4)-x1(:);
            app.UIAxes4.cla;
            hold(app.UIAxes4,'on');
            plot(app.UIAxes4,app.ffs(:,2),app.ycorr(:,1),'-b');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'Tab 1';
            app.UIAxes1 = uiaxes(app.Tab);
            title(app.UIAxes1, 'X-channel');
            xlabel(app.UIAxes1, 'Frequency, Hz');
            ylabel(app.UIAxes1, 'X');
            app.UIAxes2 = uiaxes(app.Tab);
            title(app.UIAxes2, 'Y-Channel');
            xlabel(app.UIAxes2, 'Frequency, Hz');
            ylabel(app.UIAxes2, 'Y');
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = 'Panel';
            app.Panel.Units = 'pixels';
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'End';
            app.EndSl = uislider(app.Panel);
            app.EndSl.Limits = [1 100];
            app.EndSl.ValueChangedFcn = createCallbackFcn(app, @EndSlValueChanged);
            app.EndSl.Value = 1;
            app.Label2 = uilabel(app.Panel);
            app.Label2.Text = 'Begin';
            app.Begsl = uislider(app.Panel);
            app.Begsl.Limits = [1 100];
            app.Begsl.ValueChangedFcn = createCallbackFcn(app, @BegslValueChanged);
            app.Begsl.Value = 1;
            app.App_cut = uibutton(app.Panel, 'push');
            app.App_cut.ButtonPushedFcn = createCallbackFcn(app, @App_cutButtonPushed);
            app.App_cut.Text = 'Apply';
            app.ap_fit = uibutton(app.Panel, 'push');
            app.ap_fit.ButtonPushedFcn = createCallbackFcn(app, @ap_fitButtonPushed);
            app.ap_fit.Text = 'Fit';
            app.LabelNumericEditField = uilabel(app.Panel);
            app.LabelNumericEditField.Text = 'Drive V';
            app.DV = uieditfield(app.Panel, 'numeric');
            app.DV.Value = 0.1;
            app.LabelTextArea = uilabel(app.Tab);
            app.LabelTextArea.Text = 'Fit parameters';
            app.textfit = uitextarea(app.Tab);
            app.textfit.Value = {'x0'; 'x1'; 'x2'; 'x3'; ''; 'y0'; 'y1'; 'y2'; 'y3'; 'y4'};
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Tab 2';
            app.Panel2 = uipanel(app.Tab2);
            app.Panel2.Title = 'Panel';
            app.Panel2.Units = 'pixels';
            app.openrs = uibutton(app.Panel2, 'push');
            app.openrs.ButtonPushedFcn = createCallbackFcn(app, @openrsButtonPushed);
            app.openrs.Text = 'open reg sweep';
            app.recal = uibutton(app.Panel2, 'push');
            app.recal.ButtonPushedFcn = createCallbackFcn(app, @recalButtonPushed);
            app.recal.Text = 'recall';
            app.filt = uibutton(app.Panel2, 'push');
            app.filt.ButtonPushedFcn = createCallbackFcn(app, @filtButtonPushed);
            app.filt.Text = 'Filter';
            app.LabelNumericEditField2 = uilabel(app.Panel2);
            app.LabelNumericEditField2.Text = 'Filter num';
            app.filter_num = uieditfield(app.Panel2, 'numeric');
            app.filter_num.ValueChangedFcn = createCallbackFcn(app, @filter_numValueChanged);
            app.filter_num.Limits = [1 Inf];
            app.filter_num.Value = 3;
            app.LabelSlider = uilabel(app.Panel2);
            app.LabelSlider.Text = 'X0';
            app.X_0 = uislider(app.Panel2);
            app.X_0.Limits = [-1 1];
            app.X_0.ValueChangedFcn = createCallbackFcn(app, @X_0ValueChanged);
            app.Label3 = uilabel(app.Panel2);
            app.Label3.Text = 'X1';
            app.X_1 = uislider(app.Panel2);
            app.X_1.Limits = [-1 1];
            app.X_1.ValueChangedFcn = createCallbackFcn(app, @X_1ValueChanged);
            app.Label4 = uilabel(app.Panel2);
            app.Label4.Text = 'Y0';
            app.Y_0 = uislider(app.Panel2);
            app.Y_0.Limits = [-1 1];
            app.Y_0.ValueChangedFcn = createCallbackFcn(app, @Y_0ValueChanged);
            app.Label5 = uilabel(app.Panel2);
            app.Label5.Text = 'Y1';
            app.Y_1 = uislider(app.Panel2);
            app.Y_1.Limits = [-1 1];
            app.Y_1.ValueChangedFcn = createCallbackFcn(app, @Y_1ValueChanged);
            app.UIAxes3 = uiaxes(app.Tab2);
            title(app.UIAxes3, 'X-Channel SSweep');
            xlabel(app.UIAxes3, 'Frequency, Hz');
            ylabel(app.UIAxes3, 'Xcorr');
            app.UIAxes4 = uiaxes(app.Tab2);
            title(app.UIAxes4, 'Y-Channel SSweep');
            xlabel(app.UIAxes4, 'Frequency, Hz');
            ylabel(app.UIAxes4, 'Ycorr');
            app.Closeapp = uibutton(app.Tab2, 'push');
            app.Closeapp.ButtonPushedFcn = createCallbackFcn(app, @CloseappButtonPushed);
            app.Closeapp.Text = 'Close app';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Units = 'pixels';
            app.Tab3.Title = 'Tab 3';
            app.UIAxes5 = uiaxes(app.Tab3);
            title(app.UIAxes5, 'Ycorr vs Xcorr');
            xlabel(app.UIAxes5, 'Xcorr');
            ylabel(app.UIAxes5, 'Ycorr');
            app.UIAxes6 = uiaxes(app.Tab3);
            title(app.UIAxes6, 'Rcorr');
            xlabel(app.UIAxes6, 'Frequency, Hz');
            ylabel(app.UIAxes6, 'Rcorr');
            app.LabelTextArea2 = uilabel(app.Tab3);
            app.LabelTextArea2.Text = 'Phase shift';
            app.phsh = uitextarea(app.Tab3);
            app.phsh.Value = {'phi'};
            app.save_but = uibutton(app.Tab3, 'push');
            app.save_but.ButtonPushedFcn = createCallbackFcn(app, @save_butButtonPushed);
            app.save_but.Text = 'printAll';
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Units = 'pixels';
            app.Tab4.Title = 'Tab 4';
            app.UIAxes7 = uiaxes(app.Tab4);
            title(app.UIAxes7, 'Phase');
            xlabel(app.UIAxes7, 'Frequency [Hz]');
            ylabel(app.UIAxes7, 'Deg');
        end
    end

    methods (Access = public)

        % Construct app
        function app = fork_feed()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end